classdef mex_test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TotalTimeMEXEditField           matlab.ui.control.NumericEditField
        TotalTimeMEXEditFieldLabel      matlab.ui.control.Label
        TotaltimeMatlabNativeEditField  matlab.ui.control.NumericEditField
        TotaltimeMatlabNativeEditFieldLabel  matlab.ui.control.Label
        RMSEBetweenMethodsEditField     matlab.ui.control.NumericEditField
        RMSEBetweenMethodsEditFieldLabel  matlab.ui.control.Label
        RUNTESTButton                   matlab.ui.control.Button
        UIAxes_SLT_MEX                  matlab.ui.control.UIAxes
        UIAxes_SLT_NATIVE               matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNTESTButton
        function RUNTESTButtonPushed(app, event)
            % Test signal parameters
            duration_sweep = 2; % seconds
            duration_silence = 0.5; % Seconds
            fs = 2000; % Hz
            f1 = 100; % Hz
            f2 = 800; % Hz
            amf1 = 30; % Hz
            amf2 = 3; % Hz
            
            % Synthesise test signal
            samps_sweep = (duration_sweep*fs);
            samps_total = (duration_sweep*fs)+(duration_silence*fs*2);
            t_vec_sweep = linspace(1, duration_sweep, samps_sweep);
            t_vec_total = linspace(1, duration_sweep+duration_silence, samps_total);
            sweep = rescale(chirp(t_vec_sweep, f1, t_vec_sweep(end), f2), -1, 1);
            modulator = rescale(sign(chirp(t_vec_sweep, amf1, t_vec_sweep(end),...
                amf2, [], -90)), 0, 1);
            noisy = rescale(rand(1, samps_total), -0.1, 0.1);
            silence = zeros(1, fs*duration_silence);
            testsig = noisy + [silence, (sweep .* modulator), silence];
            
            % Superlet Parameters
            Fi = [1, (fs/2)-1];
            fres = 1;
            c1 = 4;
            o = [10, 40];
            mult = 0.5;
            Nf = range(Fi)/fres;
            f_vec = linspace(Fi(1), Fi(2), Nf);

            % Begin Profiling
            profile on

            % Run native implementaion of faslt analysis
            SLT = nfaslt(testsig, fs, Fi, Nf, c1, o, mult);

            % Run MEX implementation of faslt analysis
            SLT_mex = faslt_mex(testsig, fs, Fi, Nf, c1, o, mult);

            % Finish Profiling
            p = profile('info');

            % Display profiling results
            native_linenum = find(strcmp({p.FunctionTable.FunctionName}, 'nfaslt'));
            mex_linenum = find(strcmp({p.FunctionTable.FunctionName}, 'faslt_mex'));       
            app.TotaltimeMatlabNativeEditField.Value = p.FunctionTable(native_linenum).TotalTime;
            app.TotalTimeMEXEditField.Value = p.FunctionTable(mex_linenum).TotalTime;

            % Check Error & Display on UI field
            app.RMSEBetweenMethodsEditField.Value = rmse(SLT, SLT_mex, 'all');

            % Plot results
            s1 = pcolor(app.UIAxes_SLT_NATIVE, t_vec_total, f_vec, SLT);
            s1.EdgeColor = 'none';

            s2 = pcolor(app.UIAxes_SLT_MEX, t_vec_total, f_vec, SLT_mex);
            s2.EdgeColor = 'none';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_SLT_NATIVE = uiaxes(app.UIFigure);
            title(app.UIAxes_SLT_NATIVE, 'Matlab Native "nfaslt.m"')
            xlabel(app.UIAxes_SLT_NATIVE, 'X')
            ylabel(app.UIAxes_SLT_NATIVE, 'Y')
            zlabel(app.UIAxes_SLT_NATIVE, 'Z')
            app.UIAxes_SLT_MEX = uiaxes(app.UIFigure);
            title(app.UIAxes_SLT_MEX, 'Mex Implementation "faslt_mex.mexw64"')
            xlabel(app.UIAxes_SLT_MEX, 'X')
            ylabel(app.UIAxes_SLT_MEX, 'Y')
            zlabel(app.UIAxes_SLT_MEX, 'Z')
            app.RUNTESTButton = uibutton(app.UIFigure, 'push');
            app.RUNTESTButton.ButtonPushedFcn = createCallbackFcn(app, @RUNTESTButtonPushed, true);
            app.RUNTESTButton.Text = 'RUN TEST';
            app.RMSEBetweenMethodsEditFieldLabel = uilabel(app.UIFigure);
            app.RMSEBetweenMethodsEditFieldLabel.Text = 'RMSE Between Methods';
            app.RMSEBetweenMethodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RMSEBetweenMethodsEditField.Editable = 'off';
            app.TotaltimeMatlabNativeEditFieldLabel = uilabel(app.UIFigure);
            app.TotaltimeMatlabNativeEditFieldLabel.Text = 'Total time (Matlab Native) ';
            app.TotaltimeMatlabNativeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalTimeMEXEditFieldLabel = uilabel(app.UIFigure);
            app.TotalTimeMEXEditFieldLabel.Text = 'Total Time (MEX)';
            app.TotalTimeMEXEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mex_test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end